classdef appTestMiaer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        DataReceivedTextArea       matlab.ui.control.TextArea
        DataReceivedTextAreaLabel  matlab.ui.control.Label
        SNDLamp                    matlab.ui.control.Lamp
        SNDLampLabel               matlab.ui.control.Label
        ERRLamp                    matlab.ui.control.Lamp
        ERRLampLabel               matlab.ui.control.Label
        DCBLamp                    matlab.ui.control.Lamp
        DCBLampLabel               matlab.ui.control.Label
        RTCBLamp                   matlab.ui.control.Lamp
        RTCBLampLabel              matlab.ui.control.Label
        CommandDropDown            matlab.ui.control.DropDown
        CommandDropDownLabel       matlab.ui.control.Label
        CMDLamp                    matlab.ui.control.Lamp
        CMDLampLabel               matlab.ui.control.Label
        ConnectButton              matlab.ui.control.Button
        RightPanel                 matlab.ui.container.Panel
        DFAxes                     matlab.ui.control.UIAxes
        SpectrunAxes               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        config; % Store configuration structure read from json file at startup
        cmd; % Store command channel socket connection
        rtdcb; % Store realtime datacallback channel socket connection
        dcb; % Store data callback channel socket connection
        ercb; % Store error callback command channel socket connection
        snd; % Store sound channel socket connection
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % load client config and command samples
            [app.config, command_list] = loadConf();

            app.CommandDropDown.Value = string(fieldnames(command_list));
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            [app.cmd,app.rtdcb,app.dcb,app.ercb,app.snd] = connect(app.config);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {608, 608};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {212, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ConnectButton = uibutton(app.LeftPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.CMDLampLabel = uilabel(app.LeftPanel);
            app.CMDLampLabel.Text = 'CMD';
            app.CMDLamp = uilamp(app.LeftPanel);
            app.CMDLamp.Tooltip = {'Command connection activity. Channel used to by the client to send commands'};
            app.CommandDropDownLabel = uilabel(app.LeftPanel);
            app.CommandDropDownLabel.Text = 'Command';
            app.CommandDropDown = uidropdown(app.LeftPanel);
            app.RTCBLampLabel = uilabel(app.LeftPanel);
            app.RTCBLampLabel.Text = 'RTCB';
            app.RTCBLamp = uilamp(app.LeftPanel);
            app.RTCBLamp.Tooltip = {'RealTime Callback connection activity.. Used for binary streaming for traces, occupancy and DF sweeps'};
            app.DCBLampLabel = uilabel(app.LeftPanel);
            app.DCBLampLabel.Text = 'DCB';
            app.DCBLamp = uilamp(app.LeftPanel);
            app.DCBLamp.Tooltip = {'Data Callback connection activity. Used for measurements, bist and singular data'};
            app.ERRLampLabel = uilabel(app.LeftPanel);
            app.ERRLampLabel.Text = 'ERR';
            app.ERRLamp = uilamp(app.LeftPanel);
            app.ERRLamp.Tooltip = {'Error Callback connection activity. Return erros from station and connection services to the client'};
            app.SNDLampLabel = uilabel(app.LeftPanel);
            app.SNDLampLabel.Text = 'SND';
            app.SNDLamp = uilamp(app.LeftPanel);
            app.SNDLamp.Tooltip = {'Sound connection activity. Indicates that sound streaming is being performed.'};
            app.DataReceivedTextAreaLabel = uilabel(app.LeftPanel);
            app.DataReceivedTextAreaLabel.Text = 'Data Received';
            app.DataReceivedTextArea = uitextarea(app.LeftPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.SpectrunAxes = uiaxes(app.RightPanel);
            xlabel(app.SpectrunAxes, 'Frequency')
            ylabel(app.SpectrunAxes, 'Level')
            zlabel(app.SpectrunAxes, 'Z')
            app.DFAxes = uiaxes(app.RightPanel);
            xlabel(app.DFAxes, 'Frequency')
            ylabel(app.DFAxes, 'Angle')
            zlabel(app.DFAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appTestMiaer

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end