classdef appTestMiaer_v0_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MIAerTestUIFigure          matlab.ui.Figure
        mainGrid                   matlab.ui.container.GridLayout
        DataReceivedTextAreaLabel  matlab.ui.control.Label
        TabGroup                   matlab.ui.container.TabGroup
        SNDTab                     matlab.ui.container.Tab
        sndGrid                    matlab.ui.container.GridLayout
        Knob                       matlab.ui.control.Knob
        KnobLabel                  matlab.ui.control.Label
        Gauge                      matlab.ui.control.SemicircularGauge
        GaugeLabel                 matlab.ui.control.Label
        ERRTab                     matlab.ui.container.Tab
        errGrid                    matlab.ui.container.GridLayout
        errText                    matlab.ui.control.TextArea
        Label_2                    matlab.ui.control.Label
        RTDTab                     matlab.ui.container.Tab
        rtdGrid                    matlab.ui.container.GridLayout
        rtdText                    matlab.ui.control.TextArea
        TextArea_2Label            matlab.ui.control.Label
        DCBTab                     matlab.ui.container.Tab
        dcbGrid                    matlab.ui.container.GridLayout
        dcbTree                    matlab.ui.container.Tree
        Label_3                    matlab.ui.control.Label
        dcbText                    matlab.ui.control.TextArea
        CMDTab                     matlab.ui.container.Tab
        cmdGrid                    matlab.ui.container.GridLayout
        cmdText                    matlab.ui.control.TextArea
        Label                      matlab.ui.control.Label
        LastCmdText                matlab.ui.control.TextArea
        RepeatButton               matlab.ui.control.Button
        CMDButton                  matlab.ui.control.Button
        DCBButton                  matlab.ui.control.Button
        RTDButton                  matlab.ui.control.Button
        ERRButton                  matlab.ui.control.Button
        SNDButton                  matlab.ui.control.Button
        ConnectButton              matlab.ui.control.StateButton
        CommandDropDown            matlab.ui.control.DropDown
        CommandDropDownLabel       matlab.ui.control.Label
        SPTAxes                    matlab.ui.control.UIAxes
        OCCAxes                    matlab.ui.control.UIAxes
        AOAAxes                    matlab.ui.control.UIAxes
    end

    
    %-----------------------------------------------------------------%
    properties (Constant)
        % app constants -----------------------------------------%
        RED = struct('lamp',[0.64,0.08,0.18],'text',[1.0,1.0,1.0])
        GREEN = struct('lamp',[0.47,0.67,0.19],'text',[0.0,0.0,0.0])
        BLUE = struct('lamp',[0.30,0.75,0.93],'text',[0.0,0.0,0.0])
        YELLOW = struct('lamp',[0.93,0.69,0.13],'text',[0.0,0.0,0.0])

        CHANNEL = struct('CMD',1,'DCB',2,'RTD',3,'ERR',4,'SND',5)
        CHANNEL_NAME = {'Command','Data Callback','Real Time Data','Error','Sound'}

        % TCI constants -----------------------------------------%
        PAN_OFFSET = 192
        PAN_DATA_TYPE = '8'
    end

    %-----------------------------------------------------------------%
    properties (Access = public)
        config; % Store configuration structure read from json file at startup
        connection = cell(5,1)% Store command channel socket connection
    end
    
    %-----------------------------------------------------------------%
    methods (Access = public)
        
        %-----------------------------------------------------------------%
        function loadConf(app)
        %LOADCONF load app configuration from json file
        %   Use a default json file to define constants that control the
        %   application and further load command examples in json format to populate the command list.

            try
                % try to read the json file
                app.config = jsondecode(fileread('appConfig.json'));
            catch
                % use default values if the file is not found
                warning('Failed to read configuration from file appConfig.json');
                app.config = struct('log', struct('file', struct('enable', true, 'level', 'trace', 'path', 'log.txt')), ...
                                                'proxy', struct('address', 'localhost', 'timeout_s', 10), ...
                                                'service', struct('command', struct('port', 3000, 'timeout_s', 10000, 'sleep_ms', 100, 'check_period', 10,'path','D:/Documents/Anatel/Aplicativos/GitHub/MIAerConnVS/test'), ...
                                                                    'stream', struct('port', 3001, 'timeout_s', 10000, 'sleep_ms', 500, 'check_period', 200), ...
                                                                    'error', struct('port', 3002, 'timeout_s', 10000, 'sleep_ms', 500, 'check_period', 200), ...
                                                                    'realtime', struct('port', 3003, 'timeout_s', 10000, 'sleep_ms', 500, 'check_period', 200)));
            end
        
            % list json files present in the app folder
            cd (app.config.service.command.path);
            files = dir('cmd*.json');
        
            % load the command list from the json files
            items_size = length(files)+1;
            cmd_items{items_size} = [];
            cmd_values{items_size} = [];
            
            cmd_items{1} =  '<select a command>';
            cmd_values{1} = '';
        
            for i = 2:items_size
                try
                    % create a command name by removing the initial lettes 'cmd' and the extension '.json' from the file name
                    filename = files(i-1).name;
                    cmd_items{i} = filename(4:end-5);
                    cmd_values{i} = fileread(filename);
                catch
                    % skip the file if it is not in json format
                    warning('Failed to read command list from file %s', filename);
                    continue;
                end
            end

            app.CommandDropDown.Items = cmd_items;
            app.CommandDropDown.ItemsData = cmd_values;
        
        end
        
        %-----------------------------------------------------------------%
        function lampSwitch(app,lamp,colour)
            % Change lamp colour according to index and value
            switch lamp
                case app.CHANNEL.CMD
                    app.CMDButton.BackgroundColor = colour.lamp;
                    app.CMDButton.FontColor = colour.text;
                case app.CHANNEL.DCB
                    app.DCBButton.BackgroundColor = colour.lamp;
                    app.DCBButton.FontColor = colour.text;
                case app.CHANNEL.RTD
                     app.RTDButton.BackgroundColor = colour.lamp;
                     app.RTDButton.FontColor = colour.text;
                case app.CHANNEL.ERR
                     app.ERRButton.BackgroundColor = colour.lamp;
                     app.ERRButton.FontColor = colour.text;
                case app.CHANNEL.SND
                     app.SNDButton.BackgroundColor = colour.lamp;
                     app.SNDButton.FontColor = colour.text;
            end
        end

        %-----------------------------------------------------------------%
        function lampBlueBlink(app,lamp)
            lampSwitch(app,lamp,app.BLUE);
            pause(1);
            lampSwitch(app,lamp,app.GREEN);
        end

        %-----------------------------------------------------------------%
        function connect(app)
            % Connect the various socket interfaces desginated as channels
            % and set corresponding lamp indication in the UI

            try
                app.connection{app.CHANNEL.CMD} = tcpclient(app.config.proxy.address, app.config.service.command.port, 'Timeout', app.config.service.command.timeout_s, 'ConnectTimeout', app.config.service.command.timeout_s, 'Tag', 'cmd');
                configureCallback(app.connection{app.CHANNEL.CMD},"terminator",@app.cmdChannelCB);
                configureTerminator(app.connection{app.CHANNEL.CMD},"LF");
                app.lamp_switch(app.CHANNEL.CMD,app.GREEN);
            catch
                warning('Failed to connect to the command channel');
                app.lamp_switch(app.CHANNEL.CMD,app.RED);
                app.connection{app.CHANNEL.CMD} = [];
            end
        
            try
                app.connection{app.CHANNEL.DCB} = tcpclient(app.config.proxy.address,app.config.service.data.port, 'Timeout',app.config.service.data.timeout_s, 'ConnectTimeout',app.config.service.data.timeout_s, 'Tag', 'dcb');
                configureCallback(app.connection{app.CHANNEL.DCB},"terminator",@app.dcbChannelCB);
                configureTerminator(app.connection{app.CHANNEL.DCB},"LF");
                app.lamp_switch(app.CHANNEL.DCB,app.GREEN);
            catch
                warning('Failed to connect to the data channel');
                app.lamp_switch(app.CHANNEL.DCB,app.RED);
                app.connection{app.CHANNEL.DCB} = [];
            end

            try
                app.connection{app.CHANNEL.RTD} = tcpclient(app.config.proxy.address,app.config.service.realtime.port, 'Timeout',app.config.service.realtime.timeout_s, 'ConnectTimeout',app.config.service.realtime.timeout_s, 'Tag', 'rtdcb');
                configureCallback(app.connection{app.CHANNEL.RTD},"terminator",@app.rtdChannelCB);
                configureTerminator(app.connection{app.CHANNEL.RTD},"LF");
                app.lampSwitch(app.CHANNEL.RTD,app.GREEN);
            catch
                warning('Failed to connect to the realtime channel');
                app.lampSwitch(app.CHANNEL.RTD,app.RED);
                app.connection{app.CHANNEL.RTD} = [];
            end        
        
            try
                app.connection{app.CHANNEL.ERR} = tcpclient(app.config.proxy.address,app.config.service.error.port, 'Timeout',app.config.service.error.timeout_s, 'ConnectTimeout',app.config.service.error.timeout_s, 'Tag', 'ercb');
                configureCallback(app.connection{app.CHANNEL.ERR},"terminator",@app.errChannelCB);
                configureTerminator(app.connection{app.CHANNEL.ERR},"LF");
                app.lampSwitch(app.CHANNEL.ERR,app.GREEN);
            catch
                warning('Failed to connect to the error channel');
                app.lampSwitch(app.CHANNEL.ERR,app.RED);
                app.connection{app.CHANNEL.ERR} = [];
            end
        
            %{
            try
                app.connection{app.CHANNEL.SND} = tcpclient(app.config.proxy.address,app.config.service.sound.port, 'Timeout',app.config.service.sound.timeout_s, 'ConnectTimeout',app.config.service.sound.timeout_s, 'Tag', 'snd');
                configureCallback(app.connection{app.CHANNEL.SND},"terminator",@app.sndChannelCB);
                configureTerminator(app.connection{app.CHANNEL.SND},"CR/LF");                
                app.lampSwitch(app.CHANNEL.SND,app.GREEN);
            catch
                warning('Failed to connect to the sound channel');
                app.lampSwitch(app.CHANNEL.SND,app.RED);
                app.connection{app.CHANNEL.SND} = [];
            end 
            %}
        end

        %-----------------------------------------------------------------%
        function disconnect(app)
            % Disconnect all channels and reset lamps

            for i = app.CHANNEL.CMD:app.CHANNEL.ERR % change to 5 to include sound
                try
                    flush(app.connection{i});
                    clear app.connection{i};
                    delete(app.connection{i});
                    app.lamp_switch(i,app.RED);
                catch
                    warning('Failed to disconnect from %s channel.',app.CHANNEL_NAME{i});
                    app.lamp_switch(i,app.YELLOW_LAMP);
                end
            end
        end

        %-----------------------------------------------------------------%
        function cmdChannelCB(app, src, ~)
            % Handle incomming data in the command channel
            parfeval(@app.lampBlueBlink, 0, app.CHANNEL.CMD);
            data = read(src,src.NumBytesAvailable,"string");
            app.cmdText.Value = [data; app.cmdText.Value];
            app.TabGroup.SelectedTab = app.CMDTab;
        end

        %-----------------------------------------------------------------%
        function dcbChannelCB(app, src, ~)
            % Handle incomming data in the data calback channel
            parfeval(@app.lampBlueBlink, 0, app.CHANNEL.DCB);
            %app.lampBlueBlink(app,app.CHANNEL.DCB);
            data = read(src,src.NumBytesAvailable,"string");
            data = app.processData(data);
            app.presentData(data,false);
            app.TabGroup.SelectedTab = app.DCBTab;
        end

        function rtdChannelCB(app, src, ~)
            % Handle incomming data in the data calback channel
            parfeval(@app.lampBlueBlink, 0, app.CHANNEL.RTD);
            %app.lampBlueBlink(app,app.CHANNEL.RTD);
            data = read(src,src.NumBytesAvailable,"string");
            app.rtdText.Value = [data;app.rtdText.Value];
            app.TabGroup.SelectedTab = app.RTDTab;
        end
%{
'{"SGetPanResp":{"binData":"GGH<HIIJHLNMAIMKDBHHFEKKJNNGFKMPNJEBKJFILNOONLGGKJKNPNLEALQRQPMOTVTQJHRSJSUTSMOUWXZXZ]W[]\\][WSRVYYZZXXUOOSPGLNQSRNNMILNMPQTWVOHNSRKIMME@KLOSSQOG<JORQKKONEHJIGBA;?AHICMLIDFLONHHKJMPNMLA@>MNGFLMKFCDDLNMKLLOQRQOKJPLKLGINMJDFLD>JIJLFKQPMMKH@CLNOQRKQVRHQSGSWRNTSUTOLNURNQPQAVXXWUY\\ZXVUTQMNSURQRRTVY\\[OTWUSRNC8HPROONLKHIJD@1GLGKOIHKJHDHJJLONMIALOPMNLGGGLNHENRRNMTZ]]][RIOKUU\\_^[[[UTW[^a`[[cfdcbb`a_Z`b`flnnoninolige`P[\\dgeYW`ccecZXWYYVaa[WZ\\XQZZVX[[ZXSKVXVTBUUPRVTQQTUUMJMHMTUOPSO@JMKDAGFCIORRQNJBACGGOSSO9IMNPPOAEOSQVYVSTVUOLLLLLONKVZYXWQFOVVSTUVVSQOMTSUTFVWVXXUQSRKBDHEEEAJKI=EEFHG:HLJIHJOQQOLKGCLNGJPPLLQPMIKGGHF:JBKFHLJKJIIJKOQOJIKLILOMCAEEMMHHBHILMH=?4CHDCGGGMMLOMNSSOLLORQPQQNNHPTQLMLGKMOOLOOQSQMLI=HQQOQUWVTUWXYYUOJAINJQRPRPIPTUONTP@QRNPQK;IMIDKJIIDGLKGMOKCBIMNKLJ;HNQQJKMMKKPQPOMHLPPMIKLFHLMJIKKLMHI","binSize":{"internal":2400000},"conversionFactorForFS":8.541889407470478e-128,"dateTime":45573.44784874388,"freq":{"internal":194496000000},"nActiveAudioChannels":0,"numBins":801,"powerDbm":113,"rcvrAtten":20,"status":0},"respType":"8"}'
"{"SGetPanResp":{"binData":"QONNNLKIGHIJJONEMNHLPNLOPQQOJ9MPNIKI?LPPNMLHEJML@JMMLHKKDKINONNKMMNNPPKIRTUSOTWTLLEFQSQOEQWZ[[UPWZ_`\\RNWWLPQMGQSTWWTOLJCBHORRQPJ<INPPK>MRTSQOOMDJKKOQRPH<JOPPQMHPPL>KOLKMKGJJGE>LQQOIAKMJB?@INMIG>FFD4HHDLLFDHKJFGIFEJNOMCJKHJFJHHNNLMNNIDE=@FMKHRPOSOY^\\TJUXURRNUWUUWWUUWUNMQTRKLMWXTSXZVQ[\\YPSYXUUVXXYYUMIKJFINQOMOJHNSWWUMENQPPSUQSVVVUUUW\\_`^WNY[[beec]WUNOY\\[ZZUYaddbbbejkidbdimongXb`I]ab[afhgeed_ejlkhfdc^\\eeghfbadegfehhge`ee[^]YaaYTZYWV\\_`_Z[]^\\WTLQRRUWWVYYVOLTRIGNNPRKLKHLPOKAEOSSOH??DGLH37JNLMOKLJAHOOKKKPRGSTDROLSSSO?KGFNRRIKKFHRYZRPWXWSPCNLKNTWREOSQNVXWVTNNVURPLEHHB;BHEBD=CJNNLLJ>LNLKJMPMEEKLNNGHNNMMMKMOOKONINNMMLEIKCIGEDELMJGDFIKLNMAJLLJNPLJJHLJHJGKKFHJJJ;LNJRQNLJJKONLNMJHDFJEHI6IJJMNMI;KQSSNHFJG?FIOOMOLHBEEMSPUZYVVWYXUROQRNDLPM=F?CHCNJIIJNLHKOQPKHMOLGKNLIIHH@>BEHIJKGLNIBKMPRQMCFIIEBG@KPQPICHCF","binSize":{"internal":2400000},"conversionFactorForFS":1.976e-321,"dateTime":45574.334835321635,"freq":{"internal":194496000000},"nActiveAudioChannels":0,"numBins":801,"powerDbm":100,"rcvrAtten":20,"status":0},"respType":"8"}'
'"
%}
        function errChannelCB(app, src, ~)
            % Handle incomming data in the data calback channel
            parfeval(@app.lampBlueBlink, 0, app.CHANNEL.ERR);
            %app.lampBlueBlink(app,app.CHANNEL.ERR);
            data = read(src,src.NumBytesAvailable,"string");
            app.errText.Value = [data;app.errText.Value];
            app.TabGroup.SelectedTab = app.ERRTab;
        end

        function sndChannelCB(app, src, ~)
            % Handle incomming data in the data calback channel
            parfeval(@app.lampBlueBlink, 0, app.CHANNEL.SND);
            %app.lampBlueBlink(app,app.CHANNEL.SND);
            data =  read(src,src.NumBytesAvailable,"uint8");
            app.Gauge = data;
        end

        function out_data = processData(app,in_data)
            % Process diferent kinds of data from the received data into
            % adjusted structure
            json_data = jsondecode(extractBefore(in_data,strlength(in_data)-3));

            switch json_data.respType
                case app.PAN_DATA_TYPE
                    out_data = json_data.SGetPanResp;
                    out_data  = rmfield(out_data,"binData");
                    out_data.type = json_data.respType;
                    out_data.binSize = out_data.binSize.internal;
                    out_data.freq = out_data.freq.internal;
                    out_data.dateTime = datetime(out_data.dateTime, "ConvertFrom","excel"); % TCI use ColeTime, which equals to excell time.
                    out_data.LevelTrace = cast(json_data.SGetPanResp.binData, "int32") - app.PAN_OFFSET;
                otherwise
                    out_data = json_data;
            end
        end

        function presentData(app,data,refresh)
            % Process diferent kinds of data from the received data into
            % adjusted structure
            switch data.type
                case app.PAN_DATA_TYPE
                    app.dcbTree.Visible="on";
                    root = uitreenode(app.dcbTree,'Text','SGetPanResp');
                    uitreenode(root,'Text','respType: 8');
                    uitreenode(root,'Text',sprintf('freq.internal: %d',data.freq));
                    uitreenode(root,'Text',sprintf('binSize.internal: %d',data.binSize));
                    uitreenode(root,'Text',sprintf('numBins: %d',data.numBins));
                    uitreenode(root,'Text',sprintf('conversionFactorForFS: %g',data.conversionFactorForFS));
                    uitreenode(root,'Text',sprintf('dateTime: %s',string(data.dateTime,"yyyy-MMM-dd HH:mm:ss.SSS")));
                    uitreenode(root,'Text',sprintf('nActiveAudioChannels: %d',data.nActiveAudioChannels));
                    uitreenode(root,'Text',sprintf('powerDbm: %d',data.powerDbm));
                    uitreenode(root,'Text',sprintf('rcvrAtten: %d',data.rcvrAtten));
                    uitreenode(root,'Text',sprintf('status: %d',data.status));

                    if refresh
                        hold(app.SPTAxes,"off")
                    else
                        hold(app.SPTAxes,"on")
                    end
                    xlim(app.SPTAxes,[1 data.numBins])
                    plot(app.SPTAxes,data.LevelTrace,'-', 'Color',[0.2 0.5 0.9 0.1], 'LineWidth',2.5);
                otherwise
                    app.dcbTree.Visible="off";
                    app.dcbText.Value = [data;app.dcbText.Value];
            end
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % load client config, command samples and start app
            app.loadConf();
            pause on
        end

        % Value changed function: ConnectButton
        function ConnectButtonValueChanged(app, event)
            if app.ConnectButton.Value
                app.ConnectButton.Enable = false;
                app.connect();
                app.ConnectButton.Text = 'Disconnect';
                    app.ConnectButton.Enable = true;
            else
                app.ConnectButton.Enable = false;
                app.disconnect();
                app.ConnectButton.Text = 'Connect';
                app.ConnectButton.Enable = true;
            end
        end

        % Clicked callback: CommandDropDown
        function CommandDropDownClicked(app, event)
            %  change color and first item value to indicate that options
            %  are active.
            app.CommandDropDown.FontColor = [0.0, 0.0, 0.0];
            app.CommandDropDown.Items{1} = '<none>';
        end

        % Value changed function: CommandDropDown
        function CommandDropDownValueChanged(app, event)
            if app.CommandDropDown.ValueIndex > 1
                app.LastCmdText.Value = app.CommandDropDown.Value;
                write(app.connection{app.CHANNEL.CMD},app.CommandDropDown.Value);
            end
        end

        % Button pushed function: CMDButton
        function CMDButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.CMDTab;
        end

        % Button pushed function: DCBButton
        function DCBButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.DCBTab;
        end

        % Button pushed function: RTDButton
        function RTDButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.RTDTab;
        end

        % Button pushed function: RepeatButton
        function RepeatButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.CMDTab;
            write(app.connection{1},app.LastCmdText.Value);
        end

        % Button pushed function: ERRButton
        function ERRButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.ERRTab;
        end

        % Button pushed function: SNDButton
        function SNDButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.SNDTab;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MIAerTestUIFigure = uifigure('Visible', 'off');
            app.MIAerTestUIFigure.Name = 'MIAerTest';
            app.MIAerTestUIFigure.Icon = fullfile(pathToMLAPP, 'networklocationicon-200x200.png');
            app.mainGrid = uigridlayout(app.MIAerTestUIFigure);
            app.AOAAxes = uiaxes(app.mainGrid);
            xlabel(app.AOAAxes, 'Frequency')
            ylabel(app.AOAAxes, 'Angle')
            zlabel(app.AOAAxes, 'Z')
            app.OCCAxes = uiaxes(app.mainGrid);
            xlabel(app.OCCAxes, 'Frequency')
            ylabel(app.OCCAxes, 'Occupancy')
            app.SPTAxes = uiaxes(app.mainGrid);
            xlabel(app.SPTAxes, 'Frequency')
            ylabel(app.SPTAxes, 'Level')
            zlabel(app.SPTAxes, 'Z')
            app.CommandDropDownLabel = uilabel(app.mainGrid);
            app.CommandDropDownLabel.Text = 'Command';
            app.CommandDropDown = uidropdown(app.mainGrid);
            app.CommandDropDown.Items = {'<select a command>'};
            app.CommandDropDown.ValueChangedFcn = createCallbackFcn(app, @CommandDropDownValueChanged, true);
            app.CommandDropDown.ClickedFcn = createCallbackFcn(app, @CommandDropDownClicked, true);
            app.CommandDropDown.Value = '<select a command>';
            app.ConnectButton = uibutton(app.mainGrid, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonValueChanged, true);
            app.ConnectButton.Text = 'Connect';
            app.SNDButton = uibutton(app.mainGrid, 'push');
            app.SNDButton.ButtonPushedFcn = createCallbackFcn(app, @SNDButtonPushed, true);
            app.SNDButton.Text = 'SND';
            app.ERRButton = uibutton(app.mainGrid, 'push');
            app.ERRButton.ButtonPushedFcn = createCallbackFcn(app, @ERRButtonPushed, true);
            app.ERRButton.Text = 'ERR';
            app.RTDButton = uibutton(app.mainGrid, 'push');
            app.RTDButton.ButtonPushedFcn = createCallbackFcn(app, @RTDButtonPushed, true);
            app.RTDButton.Text = 'RTD';
            app.DCBButton = uibutton(app.mainGrid, 'push');
            app.DCBButton.ButtonPushedFcn = createCallbackFcn(app, @DCBButtonPushed, true);
            app.DCBButton.Text = 'DCB';
            app.CMDButton = uibutton(app.mainGrid, 'push');
            app.CMDButton.ButtonPushedFcn = createCallbackFcn(app, @CMDButtonPushed, true);
            app.CMDButton.Text = 'CMD';
            app.RepeatButton = uibutton(app.mainGrid, 'push');
            app.RepeatButton.ButtonPushedFcn = createCallbackFcn(app, @RepeatButtonPushed, true);
            app.RepeatButton.Text = 'Repeat';
            app.LastCmdText = uitextarea(app.mainGrid);
            app.TabGroup = uitabgroup(app.mainGrid);
            app.CMDTab = uitab(app.TabGroup);
            app.CMDTab.Title = 'CMD';
            app.cmdGrid = uigridlayout(app.CMDTab);
            app.Label = uilabel(app.cmdGrid);
            app.Label.Text = '';
            app.cmdText = uitextarea(app.cmdGrid);
            app.cmdText.Editable = 'off';
            app.DCBTab = uitab(app.TabGroup);
            app.DCBTab.Title = 'DCB';
            app.dcbGrid = uigridlayout(app.DCBTab);
            app.dcbText = uitextarea(app.dcbGrid);
            app.dcbText.Editable = 'off';
            app.Label_3 = uilabel(app.dcbGrid);
            app.Label_3.Text = '';
            app.dcbTree = uitree(app.dcbGrid);
            app.RTDTab = uitab(app.TabGroup);
            app.RTDTab.Title = 'RTD';
            app.rtdGrid = uigridlayout(app.RTDTab);
            app.TextArea_2Label = uilabel(app.rtdGrid);
            app.TextArea_2Label.Text = '';
            app.rtdText = uitextarea(app.rtdGrid);
            app.ERRTab = uitab(app.TabGroup);
            app.ERRTab.Title = 'ERR';
            app.errGrid = uigridlayout(app.ERRTab);
            app.Label_2 = uilabel(app.errGrid);
            app.Label_2.Text = '';
            app.errText = uitextarea(app.errGrid);
            app.SNDTab = uitab(app.TabGroup);
            app.SNDTab.Title = 'SND';
            app.sndGrid = uigridlayout(app.SNDTab);
            app.GaugeLabel = uilabel(app.sndGrid);
            app.GaugeLabel.Text = '';
            app.Gauge = uigauge(app.sndGrid, 'semicircular');
            app.Gauge.Enable = 'off';
            app.KnobLabel = uilabel(app.sndGrid);
            app.KnobLabel.Visible = 'off';
            app.KnobLabel.Text = '';
            app.Knob = uiknob(app.sndGrid, 'continuous');
            app.Knob.Enable = 'off';
            app.Knob.Visible = 'off';
            app.DataReceivedTextAreaLabel = uilabel(app.mainGrid);
            app.DataReceivedTextAreaLabel.Text = 'Data Received';
            % Show the figure after all components are created
            app.MIAerTestUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appTestMiaer_v0_1

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.MIAerTestUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.MIAerTestUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MIAerTestUIFigure)
        end
    end
end