classdef appTestMiaer_v0_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MIAerTestUIFigure  matlab.ui.Figure
        mainGrid           matlab.ui.container.GridLayout
        cleanArea          matlab.ui.control.Image
        Label_3            matlab.ui.control.Label
        repeatCmd          matlab.ui.control.Image
        CMDText            matlab.ui.control.TextArea
        DCBText            matlab.ui.control.TextArea
        RTDText            matlab.ui.control.TextArea
        ERRText            matlab.ui.control.TextArea
        Gauge              matlab.ui.control.SemicircularGauge
        GaugeLabel         matlab.ui.control.Label
        Knob               matlab.ui.control.Knob
        KnobLabel          matlab.ui.control.Label
        Label_2            matlab.ui.control.Label
        TextArea_2Label    matlab.ui.control.Label
        RTDButton          matlab.ui.control.Button
        LastCmdText        matlab.ui.control.TextArea
        SNDButton          matlab.ui.control.Button
        ERRButton          matlab.ui.control.Button
        DCBButton          matlab.ui.control.Button
        CMDButton          matlab.ui.control.Button
        Label              matlab.ui.control.Label
        ConnectButton      matlab.ui.control.StateButton
        CommandDropDown    matlab.ui.control.DropDown
        SPTAxes            matlab.ui.control.UIAxes
        OCCAxes            matlab.ui.control.UIAxes
        AOAAxes            matlab.ui.control.UIAxes
    end

    
    %-----------------------------------------------------------------%
    properties (Constant)
        % app constants -----------------------------------------%
        RED = struct('lamp',[0.64,0.08,0.18],'text',[1.0,1.0,1.0])
        GREEN = struct('lamp',[0.47,0.67,0.19],'text',[0.0,0.0,0.0])
        BLUE = struct('lamp',[0.30,0.75,0.93],'text',[0.0,0.0,0.0])
        YELLOW = struct('lamp',[0.93,0.69,0.13],'text',[0.0,0.0,0.0])

        CHANNEL = struct('CMD',1,'DCB',2,'RTD',3,'ERR',4,'SND',5)
        CHANNEL_NAME = {'Command','Data Callback','Real Time Data','Error','Sound'}

        MHZ_MULTIPLIER = 1000000
        KHZ_MAX_VALUE = 1
        KHZ_FROM_MHZ = 1000
        GHZ_MIN_VALUE = 1000
        GHZ_FROM_MHZ = 0.001

        % MIAerConn Constants
        PACK_END = "CR/LF"

        % TCI constants -----------------------------------------%
        PAN_OFFSET = 192
        FREQ_FACTOR = 1920.0;

        PAN = 8
        S_STATE_RESP = 36
        S_SCAN_DF_VS_CHANNEL = 40
    end

    %-----------------------------------------------------------------%
    properties (Access = public)
        config % Store configuration structure read from json file at startup
        connection = cell(5,1)% Store command channel socket connection
        refresh = false % store requests for graph refresh
        selectedTab = 1 % CMD
    end
    
    %-----------------------------------------------------------------%
    methods (Access = public)
        
        %-----------------------------------------------------------------%
        function loadConf(app)
        %LOADCONF load app configuration from json file
        %   Use a default json file to define constants that control the
        %   application and further load command examples in json format to populate the command list.

            try
                % try to read the json file
                app.config = jsondecode(fileread('appConfig.json'));
            catch
                % use default values if the file is not found
                warning('Failed to read configuration from file appConfig.json');
                app.config = struct('log', struct('file', struct('enable', true, 'level', 'trace', 'path', 'log.txt')), ...
                                                'proxy', struct('address', 'localhost', 'timeout_s', 10), ...
                                                'service', struct('command', struct('port', 3000, 'timeout_s', 10000, 'sleep_ms', 100, 'check_period', 10,'path','D:/Documents/Anatel/Aplicativos/GitHub/MIAerConnVS/test'), ...
                                                                    'stream', struct('port', 3001, 'timeout_s', 10000, 'sleep_ms', 500, 'check_period', 200), ...
                                                                    'error', struct('port', 3002, 'timeout_s', 10000, 'sleep_ms', 500, 'check_period', 200), ...
                                                                    'realtime', struct('port', 3003, 'timeout_s', 10000, 'sleep_ms', 500, 'check_period', 200)));
            end
        
            % list json files present in the app folder
            cd (app.config.service.command.path);
            files = dir('cmd*.json');
        
            % load the command list from the json files
            items_size = length(files)+1;
            cmd_items{items_size} = [];
            cmd_values{items_size} = [];
            
            cmd_items{1} =  '<select a command>';
            cmd_values{1} = '';
        
            for i = 2:items_size
                try
                    % create a command name by removing the initial lettes 'cmd' and the extension '.json' from the file name
                    filename = files(i-1).name;
                    cmd_items{i} = filename(4:end-5);
                    cmd_values{i} = fileread(filename);
                catch
                    % skip the file if it is not in json format
                    warning('Failed to read command list from file %s', filename);
                    continue;
                end
            end

            app.CommandDropDown.Items = cmd_items;
            app.CommandDropDown.ItemsData = cmd_values;
        
        end
        
        %-----------------------------------------------------------------%
        function lamp = lampSwitch(app,lamp,colour)
            % Change lamp colour according to index and value
            % If no colour is passed, retrive the colour currently in use
            switch lamp
                case app.CHANNEL.CMD
                    if isempty(colour)
                        lamp = struct('lamp',app.CMDButton.BackgroundColor,'text',app.CMDButton.FontColor);
                    else
                        app.CMDButton.BackgroundColor = colour.lamp;
                        app.CMDButton.FontColor = colour.text;
                    end
                case app.CHANNEL.DCB
                    if isempty(colour)
                        lamp = struct('lamp',app.DCBButton.BackgroundColor,'text',app.DCBButton.FontColor);
                    else
                        app.DCBButton.BackgroundColor = colour.lamp;
                        app.DCBButton.FontColor = colour.text;
                    end
                case app.CHANNEL.RTD
                    if isempty(colour)
                        lamp = struct('lamp',app.RTDButton.BackgroundColor,'text',app.RTDButton.FontColor);
                    else
                         app.RTDButton.BackgroundColor = colour.lamp;
                         app.RTDButton.FontColor = colour.text;
                    end
                case app.CHANNEL.ERR
                    if isempty(colour)
                        lamp = struct('lamp',app.ERRButton.BackgroundColor,'text',app.ERRButton.FontColor);
                    else
                         app.ERRButton.BackgroundColor = colour.lamp;
                         app.ERRButton.FontColor = colour.text;
                    end
                case app.CHANNEL.SND
                    if isempty(colour)
                        lamp = struct('lamp',app.SNDButton.BackgroundColor,'text',app.SNDButton.FontColor);
                    else
                         app.SNDButton.BackgroundColor = colour.lamp;
                         app.SNDButton.FontColor = colour.text;
                    end
            end
        end

        %-----------------------------------------------------------------%
        function fakeTab(app, selection)
            % Simulate tab behaviour without using tab component

            app.Gauge.Visible = "off";
            app.Knob.Visible ="off";
            app.ERRText.Visible = "off";
            app.RTDText.Visible = "off";
            app.DCBText.Visible = "off";
            app.CMDText.Visible = "off";

            switch selection
                case app.CHANNEL.CMD
                    app.CMDText.Visible = "on";
                    app.selectedTab = app.CHANNEL.CMD;
                case app.CHANNEL.DCB
                    app.DCBText.Visible = "on";
                    app.selectedTab = app.CHANNEL.DCB;
                case app.CHANNEL.RTD
                    app.RTDText.Visible = "on";
                    app.selectedTab = app.CHANNEL.RTD;
                case app.CHANNEL.ERR
                    app.ERRText.Visible = "on";
                    app.selectedTab = app.CHANNEL.ERR;
                case app.CHANNEL.SND
                    app.Gauge.Visible = "on";
                    app.Knob.Visible ="on";
                    app.selectedTab = app.CHANNEL.SND;
            end
        end

        %-----------------------------------------------------------------%
        function doing_fine = connect(app)
            % Connect the various socket interfaces desginated as channels
            % and set corresponding lamp indication in the UI

            try
                app.connection{app.CHANNEL.CMD} = tcpclient(app.config.proxy.address, app.config.service.command.port, 'Timeout', app.config.service.command.timeout_s, 'ConnectTimeout', app.config.service.command.timeout_s, 'Tag', 'cmd');
                configureCallback(app.connection{app.CHANNEL.CMD},"terminator",@app.cmdChannelCB);
                configureTerminator(app.connection{app.CHANNEL.CMD},app.PACK_END);
                app.lampSwitch(app.CHANNEL.CMD,app.GREEN);
                doing_fine = true;
            catch
                doing_fine = false;
                warning('Failed to connect to the command channel.');
                app.lampSwitch(app.CHANNEL.CMD,app.RED);
                app.connection{app.CHANNEL.CMD} = [];
            end
        
            if doing_fine
                try
                    app.connection{app.CHANNEL.DCB} = tcpclient(app.config.proxy.address,app.config.service.data.port, 'Timeout',app.config.service.data.timeout_s, 'ConnectTimeout',app.config.service.data.timeout_s, 'Tag', 'dcb');
                    configureCallback(app.connection{app.CHANNEL.DCB},"terminator",@app.dcbChannelCB);
                    configureTerminator(app.connection{app.CHANNEL.DCB},app.PACK_END);
                    app.lampSwitch(app.CHANNEL.DCB,app.GREEN);
                catch
                    doing_fine = false;
                    warning('Failed to connect to the data channel');
                    app.lampSwitch(app.CHANNEL.DCB,app.RED);
                    app.connection{app.CHANNEL.DCB} = [];
                end
            end

            if doing_fine
                try
                    app.connection{app.CHANNEL.RTD} = tcpclient(app.config.proxy.address,app.config.service.realtime.port, 'Timeout',app.config.service.realtime.timeout_s, 'ConnectTimeout',app.config.service.realtime.timeout_s, 'Tag', 'rtdcb');
                    configureCallback(app.connection{app.CHANNEL.RTD},"terminator",@app.rtdChannelCB);
                    configureTerminator(app.connection{app.CHANNEL.RTD},app.PACK_END);
                    app.lampSwitch(app.CHANNEL.RTD,app.GREEN);
                catch
                    doing_fine = false;
                    warning('Failed to connect to the realtime channel');
                    app.lampSwitch(app.CHANNEL.RTD,app.RED);
                    app.connection{app.CHANNEL.RTD} = [];
                end
            end
        
            if doing_fine
                try
                    app.connection{app.CHANNEL.ERR} = tcpclient(app.config.proxy.address,app.config.service.error.port, 'Timeout',app.config.service.error.timeout_s, 'ConnectTimeout',app.config.service.error.timeout_s, 'Tag', 'ercb');
                    configureCallback(app.connection{app.CHANNEL.ERR},"terminator",@app.errChannelCB);
                    configureTerminator(app.connection{app.CHANNEL.ERR},app.PACK_END);
                    app.lampSwitch(app.CHANNEL.ERR,app.GREEN);
                catch
                    doing_fine = false;
                    warning('Failed to connect to the error channel');
                    app.lampSwitch(app.CHANNEL.ERR,app.RED);
                    app.connection{app.CHANNEL.ERR} = [];
                end
            end
        
            %{
            if doing_fine
                try
                    app.connection{app.CHANNEL.SND} = tcpclient(app.config.proxy.address,app.config.service.sound.port, 'Timeout',app.config.service.sound.timeout_s, 'ConnectTimeout',app.config.service.sound.timeout_s, 'Tag', 'snd');
                    configureCallback(app.connection{app.CHANNEL.SND},"terminator",@app.sndChannelCB);
                    configureTerminator(app.connection{app.CHANNEL.SND},app.PACK_END);                
                    app.lampSwitch(app.CHANNEL.SND,app.GREEN);
                catch
                    doing_fine = false;
                    warning('Failed to connect to the sound channel');
                    app.lampSwitch(app.CHANNEL.SND,app.RED);
                    app.connection{app.CHANNEL.SND} = [];
                end 
            end
            %}
        end

        %-----------------------------------------------------------------%
        function disconnect(app)
            % Disconnect all channels and reset lamps

            for i = app.CHANNEL.CMD:app.CHANNEL.ERR % CHANGE TO INCLUDE SOUND
                try
                    flush(app.connection{i});
                    clear app.connection{i};
                    delete(app.connection{i});
                    app.lampSwitch(i,app.RED);
                catch
                    warning('Failed to disconnect from %s channel.',app.CHANNEL_NAME{i});
                    app.lampSwitch(i,app.YELLOW_LAMP);
                end
            end
        end

        %-----------------------------------------------------------------%
        function cmdChannelCB(app, src, ~)
            % Handle incomming data in the command channel
            app.lampSwitch(app.CHANNEL.CMD,app.BLUE);
            data = read(src,src.NumBytesAvailable,"string");
            app.CMDText.Value = [data; app.CMDText.Value];
            % app.fakeTab(app.CHANNEL.CMD);
            pause(1);
            app.lampSwitch(app.CHANNEL.CMD,app.GREEN);
        end

        %-----------------------------------------------------------------%
        function dcbChannelCB(app, src, ~)
            % Handle incomming data in the data calback channel
            app.lampSwitch(app.CHANNEL.DCB,app.BLUE);
            data = read(src,src.NumBytesAvailable,"string");
            app.fakeTab(app.CHANNEL.DCB);
            data = app.processRawData(data);
            for i = 1:length(data)
                data(i) = app.processDataTypes(data(i));
                app.presentData(data(i));
            end
            
            app.lampSwitch(app.CHANNEL.DCB,app.GREEN);
        end

        %-----------------------------------------------------------------%
        function rtdChannelCB(app, src, ~)
            % Handle incomming data in the data calback channel
            app.lampSwitch(app.CHANNEL.RTD,app.BLUE);
            data = read(src,src.NumBytesAvailable,"string");
            app.RTDText.Value = [data;app.RTDText.Value];
            app.fakeTab(app.CHANNEL.RTD);
            app.lampSwitch(app.CHANNEL.RTD,app.GREEN);
        end

%{ Notes and Drafting area
%}
        %-----------------------------------------------------------------%
        function errChannelCB(app, src, ~)
            % Handle incomming data in the data calback channel
            app.lampSwitch(app.CHANNEL.ERR,app.BLUE);
            data = read(src,src.NumBytesAvailable,"string");
            app.ERRText.Value = [data;app.ERRText.Value];
            app.fakeTab(app.CHANNEL.ERR);
            app.lampSwitch(app.CHANNEL.ERR,app.GREEN);
        end

        %-----------------------------------------------------------------%
        function sndChannelCB(app, src, ~)
            % Handle incomming data in the data calback channel
            app.lampSwitch(app.CHANNEL.SND,app.BLUE);
            data =  read(src,src.NumBytesAvailable,"uint8");
            app.Gauge = data;
            app.fakeTab(app.CHANNEL.SND)
            app.lampSwitch(app.CHANNEL.SND,app.GREEN);
        end

        %-----------------------------------------------------------------%
        function out_data = processRawData(app, in_data)
            % Process raw data from socket into structures

            try
                data = split(in_data,"\r\n");
                out_data = data;
                for i = 1:length(data)
                    out_data(i) = jsondecode(data(i));
                end                
            catch
                warning("error parsing JSON data")
            end
        end

        %-----------------------------------------------------------------%
        function out_data = processDataTypes(app,in_data)
            % Process diferent kinds of data from the received data into
            % adjusted structure

            out_data = in_data;

            switch in_data.respType
                case app.PAN
                    out_data = in_data.SGetPanResp;

                    out_data.type = in_data.respType;
                    out_data.dateTime = datetime(in_data.dateTime, "ConvertFrom","excel"); % TCI use ColeTime, which equals to excell time.
                    out_data.binSize = in_data.binSize.internal / ( app.FREQ_FACTOR * app.MHZ_MULTIPLIER );
                    out_data.centreFreq = in_data.freq.internal / ( app.FREQ_FACTOR * app.MHZ_MULTIPLIER );
                    out_data.BW = in_data.binSize * ( out_data.numBins -1 );
                    half_band = in_data.BW / 2.0;
                    out_data.startFreq = in_data.centreFreq - half_band;
                    out_data.stopFreq = in_data.centreFreq + half_band;
                    out_data.levelTrace = cast(in_data.SGetPanResp.binData, "int32") - app.PAN_OFFSET;
                    out_data.frequencyAxis = linspace(in_data.startFreq,in_data.stopFreq,length(in_data.levelTrace));
                    out_data.freqUnit = "MHz";

                    out_data  = rmfield(out_data,"binData");
                    out_data = rmfield(out_data,"freq");
                    out_data = rmfield(out_data,"conversionFactorForFS");
                case app.S_STATE_RESP

                case app.S_SCAN_DF_VS_CHANNEL

                otherwise
                    out_data = json_data;
            end

        end
        %-----------------------------------------------------------------%
        function presentData(app,data)
            % Process diferent kinds of data from the received data into
            % adjusted structure
            
            switch data.type
                case app.PAN_DATA_TYPE
%{                    
                    root = uitreenode(app.DCBTree,'Text','SGetPanResp');
                    uitreenode(root,'Text','respType: 8');
                    uitreenode(root,'Text',sprintf('freq.internal: %d',data.freq));
                    uitreenode(root,'Text',sprintf('binSize.internal: %d',data.binSize));
                    uitreenode(root,'Text',sprintf('numBins: %d',data.numBins));
                    uitreenode(root,'Text',sprintf('conversionFactorForFS: %g',data.conversionFactorForFS));
                    uitreenode(root,'Text',sprintf('dateTime: %s',string(data.dateTime,"yyyy-MMM-dd HH:mm:ss.SSS")));
                    uitreenode(root,'Text',sprintf('nActiveAudioChannels: %d',data.nActiveAudioChannels));
                    uitreenode(root,'Text',sprintf('powerDbm: %d',data.powerDbm));
                    uitreenode(root,'Text',sprintf('rcvrAtten: %d',data.rcvrAtten));
                    uitreenode(root,'Text',sprintf('status: %d',data.status));
%}
                    
                    app.plotSpectrum(data);
                    
                    data = rmfield(data,"levelTrace");
                    data = rmfield(data,"frequencyAxis");
                    app.DCBText.Value = [jsonencode(data);app.DCBText.Value];
                otherwise
                    app.DCBText.Value = [data;app.DCBText.Value];
            end

        end

        %-----------------------------------------------------------------%
        function plotSpectrum(app, data)
            % plot spectrum data
            if app.refresh
                hold(app.SPTAxes,"off");
                app.refresh = false;
            else
                hold(app.SPTAxes,"on");
            end
            
            data = app.freqBand(data);
            xlim(app.SPTAxes,[data.startFreq data.stopFreq]);
            xlabel(app.SPTAxes,{"Frequency",data.freqUnit});
            plot(app.SPTAxes,data.frequencyAxis,data.levelTrace,'-', 'Color',[0.2 0.5 0.9 0.1], 'LineWidth',2.5);
        end

        %-----------------------------------------------------------------%
        function data = freqBand(app,data)
            % Convert frequency to GHz or kHz according to the range
            if data.startFreq < app.KHZ_MAX_VALUE
                multiplier = app.KHZ_FROM_MHZ;
                data.freqUnit = "kHz";
            elseif data.startFreq > app.GHZ_MIN_VALUE
                multiplier = app.GHZ_FROM_MHZ;
                data.freqUnit = "GHz";
            else
                % default is MHz.
                return
            end

            data.binSize = data.binSize * multiplier ;
            data.startFreq = data.startFreq * multiplier;
            data.stopFreq = data.stopFreq * multiplier;
            data.BW = data.BW  * multiplier;
            data.centreFreq = data.centreFreq * multiplier;
            data.frequencyAxis = data.frequencyAxis * multiplier;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % load client config, command samples and start app
            app.loadConf();
            pause on
        end

        % Value changed function: ConnectButton
        function ConnectButtonValueChanged(app, event)
            app.ConnectButton.Enable = false;
            if app.ConnectButton.Value
                connected = app.connect();
                if connected                    
                    app.ConnectButton.Text = 'Disconnect';
                    app.ConnectButton.Enable = true;
                else
                    warndlg(sprintf("Could not connect to server.\nPlease check server connection and operation and try again."),'Connection Failed');
                    app.ConnectButton.Value = false;
                    app.ConnectButton.Enable = true;
                end
            else
                app.ConnectButton.Enable = false;
                app.disconnect();
                app.ConnectButton.Text = 'Connect';
                app.ConnectButton.Enable = true;
            end
        end

        % Clicked callback: CommandDropDown
        function CommandDropDownClicked(app, event)
            %  change color and first item value to indicate that options
            %  are active.
            app.CommandDropDown.FontColor = [0.0, 0.0, 0.0];
            app.CommandDropDown.Items{1} = '<none>';
        end

        % Value changed function: CommandDropDown
        function CommandDropDownValueChanged(app, event)
            if app.CommandDropDown.ValueIndex > 1
                
                app.LastCmdText.Value = app.CommandDropDown.Value;
                write(app.connection{app.CHANNEL.CMD},app.CommandDropDown.Value);
            else
                app.CommandDropDown.FontColor = [0.80,0.80,0.80];
                app.CommandDropDown.Items{1} = '<select a command>';
                app.LastCmdText.Value = "";
                cla(app.SPTAxes);
                cla(app.OCCAxes);
                cla(app.AOAAxes);
            end
        end

        % Button pushed function: CMDButton
        function CMDButtonPushed(app, event)
            app.fakeTab(app.CHANNEL.CMD);
        end

        % Button pushed function: DCBButton
        function DCBButtonPushed(app, event)
            app.fakeTab(app.CHANNEL.DCB);
        end

        % Button pushed function: RTDButton
        function RTDButtonPushed(app, event)
            app.fakeTab(app.CHANNEL.RTD);
        end

        % Button pushed function: ERRButton
        function ERRButtonPushed(app, event)
            app.fakeTab(app.CHANNEL.ERR);
        end

        % Button pushed function: SNDButton
        function SNDButtonPushed(app, event)
            app.fakeTab(app.CHANNEL.SND);
        end

        % Image clicked function: repeatCmd
        function repeatCmdClicked(app, event)
            app.fakeTab(app.CHANNEL.CMD)
            write(app.connection{1},string((app.LastCmdText.Value{1})));
        end

        % Close request function: MIAerTestUIFigure
        function MIAerTestUIFigureCloseRequest(app, event)
            delete(app);
        end

        % Value changed function: LastCmdText
        function LastCmdTextValueChanged(app, event)
            app.refresh = true;
        end

        % Image clicked function: cleanArea
        function cleanAreaClicked(app, event)
            switch app.selectedTab
                case app.CHANNEL.CMD
                    app.CMDText.Value = "";
                case app.CHANNEL.DCB
                    app.DCBText.Value = "";
                case app.CHANNEL.RTD
                    app.RTDText.Value = "";
                case app.CHANNEL.ERR
                    app.ERRText.Value = "";
                case app.CHANNEL.SND
                    app.Gauge.Value = 0;
                    app.Knob.Value = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MIAerTestUIFigure = uifigure('Visible', 'off');
            app.MIAerTestUIFigure.Name = 'MIAerTest';
            app.MIAerTestUIFigure.Icon = fullfile(pathToMLAPP, 'networklocationicon-200x200.png');
            app.MIAerTestUIFigure.CloseRequestFcn = createCallbackFcn(app, @MIAerTestUIFigureCloseRequest, true);
            app.mainGrid = uigridlayout(app.MIAerTestUIFigure);
            app.AOAAxes = uiaxes(app.mainGrid);
            xlabel(app.AOAAxes, {'Frequency'; '(MHz)'})
            ylabel(app.AOAAxes, {'Angle'; '(degrees from north)'})
            zlabel(app.AOAAxes, 'Z')
            app.OCCAxes = uiaxes(app.mainGrid);
            xlabel(app.OCCAxes, {'Frequency'; '(MHz)'})
            ylabel(app.OCCAxes, {'Occupancy'; '(%)'})
            app.SPTAxes = uiaxes(app.mainGrid);
            xlabel(app.SPTAxes, {'Frequency'; '(MHz)'})
            ylabel(app.SPTAxes, {'Level'; '(dBm)'})
            zlabel(app.SPTAxes, 'Z')
            app.CommandDropDown = uidropdown(app.mainGrid);
            app.CommandDropDown.Items = {'<select a command>'};
            app.CommandDropDown.ValueChangedFcn = createCallbackFcn(app, @CommandDropDownValueChanged, true);
            app.CommandDropDown.Tooltip = {'Select a coomand and send it to the station'};
            app.CommandDropDown.ClickedFcn = createCallbackFcn(app, @CommandDropDownClicked, true);
            app.CommandDropDown.Value = '<select a command>';
            app.ConnectButton = uibutton(app.mainGrid, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonValueChanged, true);
            app.ConnectButton.Text = 'Connect';
            app.Label = uilabel(app.mainGrid);
            app.Label.Text = '';
            app.CMDButton = uibutton(app.mainGrid, 'push');
            app.CMDButton.ButtonPushedFcn = createCallbackFcn(app, @CMDButtonPushed, true);
            app.CMDButton.Tooltip = {'Click to select CMD channel output. Colour change to indicate chnnal activity.'};
            app.CMDButton.Text = ' CMD ';
            app.DCBButton = uibutton(app.mainGrid, 'push');
            app.DCBButton.ButtonPushedFcn = createCallbackFcn(app, @DCBButtonPushed, true);
            app.DCBButton.Tooltip = {'Click to select DCB channel output. Colour change to indicate chnnal activity.'};
            app.DCBButton.Text = ' DCB ';
            app.ERRButton = uibutton(app.mainGrid, 'push');
            app.ERRButton.ButtonPushedFcn = createCallbackFcn(app, @ERRButtonPushed, true);
            app.ERRButton.Tooltip = {'Click to select ERR channel output. Colour change to indicate chnnal activity.'};
            app.ERRButton.Text = ' ERR ';
            app.SNDButton = uibutton(app.mainGrid, 'push');
            app.SNDButton.ButtonPushedFcn = createCallbackFcn(app, @SNDButtonPushed, true);
            app.SNDButton.Enable = 'off';
            app.SNDButton.Tooltip = {'Click to select SND channel output. Colour change to indicate chnnal activity.'};
            app.SNDButton.Text = ' SND ';
            app.LastCmdText = uitextarea(app.mainGrid);
            app.LastCmdText.ValueChangedFcn = createCallbackFcn(app, @LastCmdTextValueChanged, true);
            app.LastCmdText.Placeholder = '< will display command allowing changes / repeat >';
            app.RTDButton = uibutton(app.mainGrid, 'push');
            app.RTDButton.ButtonPushedFcn = createCallbackFcn(app, @RTDButtonPushed, true);
            app.RTDButton.Tooltip = {'Click to select RTD channel output. Colour change to indicate chnnal activity.'};
            app.RTDButton.Text = ' RTD ';
            app.TextArea_2Label = uilabel(app.mainGrid);
            app.TextArea_2Label.Text = '';
            app.Label_2 = uilabel(app.mainGrid);
            app.Label_2.Text = '';
            app.KnobLabel = uilabel(app.mainGrid);
            app.KnobLabel.Visible = 'off';
            app.KnobLabel.Text = '';
            app.Knob = uiknob(app.mainGrid, 'continuous');
            app.Knob.Enable = 'off';
            app.Knob.Visible = 'off';
            app.GaugeLabel = uilabel(app.mainGrid);
            app.GaugeLabel.Text = '';
            app.Gauge = uigauge(app.mainGrid, 'semicircular');
            app.Gauge.Enable = 'off';
            app.Gauge.Visible = 'off';
            app.ERRText = uitextarea(app.mainGrid);
            app.ERRText.Placeholder = '< will display messages received in ERR channel >';
            app.RTDText = uitextarea(app.mainGrid);
            app.RTDText.Placeholder = '< will display messages received in RTD channel >';
            app.DCBText = uitextarea(app.mainGrid);
            app.DCBText.Placeholder = '< will display messages received in DCB channel >';
            app.CMDText = uitextarea(app.mainGrid);
            app.CMDText.Placeholder = '< will display messages received in CMD channel >';
            app.repeatCmd = uiimage(app.mainGrid);
            app.repeatCmd.ImageClickedFcn = createCallbackFcn(app, @repeatCmdClicked, true);
            app.repeatCmd.Tooltip = {'Repeat the command'};
            app.repeatCmd.ImageSource = fullfile(pathToMLAPP, 'redo.svg');
            app.Label_3 = uilabel(app.mainGrid);
            app.Label_3.Text = '';
            app.cleanArea = uiimage(app.mainGrid);
            app.cleanArea.ImageClickedFcn = createCallbackFcn(app, @cleanAreaClicked, true);
            app.cleanArea.ImageSource = fullfile(pathToMLAPP, 'sweep.svg');
            % Show the figure after all components are created
            app.MIAerTestUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appTestMiaer_v0_2

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.MIAerTestUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.MIAerTestUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MIAerTestUIFigure)
        end
    end
end